classdef brightness < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SaveButton             matlab.ui.control.Button
        BrightnessSlider       matlab.ui.control.Slider
        BrightnessSliderLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Get the current image from the UIAxes
            axesImage = getimage(app.UIAxes);
            
            if isempty(axesImage)
                % If there's no image in the UIAxes, show an error message
                errordlg('No image to save. Please adjust the brightness first.', 'Save Error');
                return;
            end
            
            % Open a save dialog to get the file name and location
            [filename, pathname] = uiputfile({'*.png','PNG Image (*.png)';...
                                              '*.jpg','JPEG Image (*.jpg)';...
                                              '*.tif','TIFF Image (*.tif)'},...
                                              'Save Image As');
            
            if isequal(filename,0) || isequal(pathname,0)
                % User canceled the save dialog
                return;
            else
                % Construct the full file path
                fullFilePath = fullfile(pathname, filename);
                
                % Save the image
                imwrite(axesImage, fullFilePath);
                
                % Show a success message
                msgbox('Image saved successfully!', 'Save Complete');
            end
        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;

            % Check if the original image data is already stored
            if isempty(app.Image.UserData)
                % Load the original image and store it in the Image's UserData
                app.Image.UserData = imread(app.Image.ImageSource);
                
                % Set slider limits
                app.BrightnessSlider.Limits = [0 200];
                app.BrightnessSlider.Value = 100;  % Set default value to middle (original brightness)
                
                % Display the original image in UIAxes
                imshow(app.Image.UserData, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Original Image');
                return;  % Exit the function after displaying the original image
            end
            
            % Get the original image data
            originalImageData = app.Image.UserData;
            
            % Convert the image to double precision for calculations
            adjustedImage = im2double(originalImageData);
            
            % Apply white balance adjustment
            % Map the slider range [0, 200] to [0.5, 2] for brightness adjustment
            brightnessScale = changingValue / 100;
            adjustedImage = adjustedImage * brightnessScale;
            
            % Clip values to ensure they're in the valid range [0, 1]
            adjustedImage = min(max(adjustedImage, 0), 1);
            
            % Display the adjusted image in UIAxes
            imshow(adjustedImage, 'Parent', app.UIAxes);
            
            % Update axis labels
            title(app.UIAxes, sprintf('Adjusted Image (Brightness: %.2f)', brightnessScale));
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'sample.jpg';
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brightness

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end