classdef cropRotate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CropButton         matlab.ui.control.Button
        SaveButton         matlab.ui.control.Button
        RotateSlider       matlab.ui.control.Slider
        RotateSliderLabel  matlab.ui.control.Label
        Image              matlab.ui.control.Image
        UIAxes             matlab.ui.control.UIAxes
    end

methods (Access = private)
        % Function to rotate and display image
        function rotateAndDisplayImage(app, angle)
            if isempty(app.Image.ImageSource)
                app.Image.ImageSource = 'sample.jpg';
            end
            
            % Load the original image data
            imageData = imread(app.Image.ImageSource);
            
            % Rotate the image
            rotatedImage = imrotate(imageData, angle, 'bilinear', 'crop');
            
            % Display rotated image
            imshow(rotatedImage, 'Parent', app.UIAxes);

            app.UIAxes.UserData.ImageSource = rotatedImage;
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isfield(app.UIAxes.UserData, 'ImageSource')
                if isfield(app.UIAxes.UserData, 'CroppedImage')
                    imageToSave = app.UIAxes.UserData.CroppedImage;
                else
                    imageToSave = app.UIAxes.UserData.ImageSource;
                end
                
                % Save the image
                [file, path] = uiputfile({'*.jpg'; '*.png'; '*.tif'}, 'Save Image As');
                if ischar(file)
                    imwrite(imageToSave, fullfile(path, file));
                end
            else
                uialert(app.UIFigure, 'No image to save.', 'Save Error');
            end
        end

        % Value changing function: RotateSlider
        function RotateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.rotateAndDisplayImage(changingValue);
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            if isfield(app.UIAxes.UserData, 'ImageSource')
                imageData = app.UIAxes.UserData.ImageSource;
        
                % Let the user draw a rectangle on the image
                h = drawrectangle(app.UIAxes);
                wait(h); % Wait for the user to double-click or press Enter
        
                % Get the position of the rectangle
                position = h.Position;
        
                % Crop the image
                croppedImage = imcrop(imageData, position);
        
                % Display the cropped image
                imshow(croppedImage, 'Parent', app.UIAxes);
        
                % Store the cropped image in UserData
                app.UIAxes.UserData.CroppedImage = croppedImage;

                delete(h);
            else
                uialert(app.UIFigure, 'No image to crop.', 'Crop Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Changes')
            colormap(app.UIAxes, 'white')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sample.jpg');
            app.RotateSliderLabel = uilabel(app.UIFigure);
            app.RotateSliderLabel.Text = 'Rotate';
            app.RotateSlider = uislider(app.UIFigure);
            app.RotateSlider.Limits = [0 360];
            app.RotateSlider.ValueChangingFcn = createCallbackFcn(app, @RotateSliderValueChanging, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cropRotate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end