classdef saturation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SaveButton             matlab.ui.control.Button
        SaturationSlider       matlab.ui.control.Slider
        SaturationSliderLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
    end

    methods (Access = public)
        % function saturate image
        function saturateImage(app, saturationMultiplier)
            if isempty(app.Image.ImageSource)
                app.Image.ImageSource = 'sample.jpg';
            end

            % Load the original image data
            imageData = imread(app.Image.ImageSource);
            %imageData = im2double(imageData);
            
            %saturate 
            saturatedImage = adjustSaturation(imageData, saturationMultiplier);
            
            % Display rotated image
            imshow(saturatedImage, 'Parent', app.UIAxes);
            
            app.UIAxes.UserData.ImageSource = saturatedImage;

        end
        
        function adjustedImage = adjustSaturation(originalImage, saturationMultiplier)
            % Convert image to double precision for calculations
            originalImage = im2double(originalImage);

            % Extract individual color channels (Red, Green, Blue)
            redChannel = originalImage(:,:,1);
            greenChannel = originalImage(:,:,2);
            blueChannel = originalImage(:,:,3);

            % Apply saturation adjustment using the multiplier
            newRed = redChannel .* (1 + saturationMultiplier);
            newGreen = greenChannel .* (1 + saturationMultiplier);
            newBlue = blueChannel .* (1 + saturationMultiplier);

            % Limit new values between 0 and 1 to prevent overflow
            newRed = min(max(newRed, 0), 1);
            newGreen = min(max(newGreen, 0), 1);
            newBlue = min(max(newBlue, 0), 1);

            % Combine adjusted channels back into a single image
            adjustedImage = cat(3, newRed, newGreen, newBlue);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            saturationMultiplier = event.Value;
            app.saturateImage(saturationMultiplier);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isfield(app.UIAxes.UserData, 'ImageSource')
                if isfield(app.UIAxes.UserData, 'SaturatedImage')
                    imageToSave = app.UIAxes.UserData.saturatedImage;
                else
                    imageToSave = app.UIAxes.UserData.ImageSource;
                end
                
                % Save the image
                [file, path] = uiputfile({'*.jpg'; '*.png'; '*.tif'}, 'Save Image As');
                if ischar(file)
                    imwrite(imageToSave, fullfile(path, file));
                end
            else
                uialert(app.UIFigure, 'No image to save.', 'Save Error');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Saturation')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.UIFigure);
            app.SaturationSliderLabel = uilabel(app.UIFigure);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.UIFigure);
            app.SaturationSlider.Limits = [0 2];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = saturation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end